classdef project2222 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        Menu_2                        matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        FilterSizeLabel               matlab.ui.control.Label
        DenoisefiltersizeSlider       matlab.ui.control.Slider
        DenoisefiltersizeSliderLabel  matlab.ui.control.Label
        FilterSizeSlider              matlab.ui.control.Slider
        Label                         matlab.ui.control.Label
        FiltersizeSliderLabel         matlab.ui.control.Label
        SaveImageButton               matlab.ui.control.Button
        UploadButton                  matlab.ui.control.Button
        CaptureButton                 matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
    end

    
properties (Access = private)
        originalImage    
        processedImage   
    end

   
    methods (Access = private)
        function processImage(app)
            if isempty(app.originalImage)
                uialert(app.UIFigure, 'No image loaded to process.', 'Error');
                return;
            end
          
            img1 = im2gray(app.originalImage);
            filterSize = round(app.FilterSizeSlider.Value);
            filt_size = round(app.DenoisefiltersizeSlider.Value);
            f1 = fspecial("average", filt_size);
            f2 = fspecial("average",filterSize)
            img1 = imfilter(img1, f1);
            img1 = double(img1);
            img1 = 255 * 0.79 * log(1 + img1) / log(256);
            img1 = 2.5 * ((img1).^4.5) * 255 / ((256)^4.5);
            img1 = uint8(img1);
            img2 = 255 - img1;
            img2 = imfilter(img2, f2);
            filtered_img = img2 + img1;
            filtered_img = imadjust(filtered_img);
            filtered_img = imadjust(filtered_img);
            app.processedImage = filtered_img;
            imshow(app.processedImage, 'Parent', app.UIAxes2);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CaptureButton
        function CaptureButtonPushed(app, event)
           cam = webcam; 
            cam.Resolution = '1280x720';
            app.originalImage = snapshot(cam); 
            clear cam;

            % Display the original image on UIAxes
            imshow(app.originalImage, 'Parent', app.UIAxes);

            % Process and display the sketch image
            app.processImage();
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
          [file, path] = uigetfile({'*.jpg;*.png;*.bmp'}, 'Select an Image');
            if isequal(file, 0)
                return; % User canceled the upload
            end

            % Load the selected image
            app.originalImage = imread(fullfile(path, file));

            % Display the original image on UIAxes
            imshow(app.originalImage, 'Parent', app.UIAxes);

            % Process and display the sketch image
            app.processImage();
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
         if isempty(app.processedImage)
                uialert(app.UIFigure, 'No processed image to save.', 'Error');
                return;
            end
            
            % Prompt the user to save the processed image
            [file, path] = uiputfile({'*.jpg;*.png'}, 'Save Processed Image');
            if isequal(file, 0)
                return; % User canceled the save action
            end
            
            % Save the processed image
            imwrite(app.processedImage, fullfile(path, file));
            uialert(app.UIFigure, 'Image saved successfully!', 'Success');
        
        end

        % Callback function
        function FiltersizeSliderValueChanged(app, event)
            value = app.FiltersizeSlider.Value;
    if ~isempty(app.originalImage)
        app.processImage();  
    end
            
        end

        % Value changing function: FilterSizeSlider
        function FilterSizeSliderValueChanging(app, event)
            changingValue = event.Value;
            
    if ~isempty(app.originalImage)
        app.processImage();  
    end
            
        end

        % Value changing function: DenoisefiltersizeSlider
        function DenoisefiltersizeSliderValueChanging(app, event)
            changingValue = event.Value;
            if ~isempty(app.originalImage)
                app.processImage();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'SKETCH IMAGE')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'ORIGINAL IMAGE')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CaptureButton = uibutton(app.GridLayout, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureButtonPushed, true);
            app.CaptureButton.Text = 'Capture';
            app.UploadButton = uibutton(app.GridLayout, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.SaveImageButton = uibutton(app.GridLayout, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.FiltersizeSliderLabel = uilabel(app.GridLayout);
            app.FiltersizeSliderLabel.Text = '';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.FilterSizeSlider = uislider(app.GridLayout);
            app.FilterSizeSlider.Limits = [1 21];
            app.FilterSizeSlider.ValueChangingFcn = createCallbackFcn(app, @FilterSizeSliderValueChanging, true);
            app.FilterSizeSlider.Value = 1;
            app.DenoisefiltersizeSliderLabel = uilabel(app.GridLayout);
            app.DenoisefiltersizeSliderLabel.Text = 'Denoise filter size';
            app.DenoisefiltersizeSlider = uislider(app.GridLayout);
            app.DenoisefiltersizeSlider.Limits = [1 7];
            app.DenoisefiltersizeSlider.MajorTicks = [1 3 5 7 9];
            app.DenoisefiltersizeSlider.MajorTickLabels = {'1', '3', '5', '7', '9'};
            app.DenoisefiltersizeSlider.ValueChangingFcn = createCallbackFcn(app, @DenoisefiltersizeSliderValueChanging, true);
            app.DenoisefiltersizeSlider.Value = 1;
            app.FilterSizeLabel = uilabel(app.GridLayout);
            app.FilterSizeLabel.Text = 'Filter Size';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project2222

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end